classdef buildMobileRobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        UpdateVisualizationButton       matlab.ui.control.Button
        ConverttoSimulinkModelButton    matlab.ui.control.Button
        AutoUpdateVisualizationCheckBox  matlab.ui.control.CheckBox
        LengthUnitsDropDown_14          matlab.ui.control.DropDown
        GripperDimensionsEditField      matlab.ui.control.EditField
        GripperDimensionsEditFieldLabel  matlab.ui.control.Label
        LengthUnitsDropDown_13          matlab.ui.control.DropDown
        EndEffectorLengthEditField      matlab.ui.control.NumericEditField
        EndEffectorLengthEditFieldLabel  matlab.ui.control.Label
        LengthUnitsDropDown_12          matlab.ui.control.DropDown
        EndEffectorRadiusEditField      matlab.ui.control.NumericEditField
        EndEffectorRadiusEditFieldLabel  matlab.ui.control.Label
        LengthUnitsDropDown_11          matlab.ui.control.DropDown
        ArmLinkThicknessesEditField     matlab.ui.control.EditField
        ArmLinkThicknessesEditFieldLabel  matlab.ui.control.Label
        LengthUnitsDropDown_10          matlab.ui.control.DropDown
        ArmLinkWidthsEditField          matlab.ui.control.EditField
        ArmLinkWidthsEditFieldLabel     matlab.ui.control.Label
        LengthUnitsDropDown_9           matlab.ui.control.DropDown
        ArmLinkLengthsEditField         matlab.ui.control.EditField
        ArmLinkLengthsEditFieldLabel    matlab.ui.control.Label
        LengthUnitsDropDown_8           matlab.ui.control.DropDown
        ArmBaseThicknessEditField       matlab.ui.control.NumericEditField
        ArmBaseThicknessEditFieldLabel  matlab.ui.control.Label
        LengthUnitsDropDown_7           matlab.ui.control.DropDown
        ArmBaseRadiusEditField          matlab.ui.control.NumericEditField
        ArmBaseRadiusEditFieldLabel     matlab.ui.control.Label
        ArmLabel                        matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        AngleUnitsDropDown              matlab.ui.control.DropDown
        RollerAnglesEditField           matlab.ui.control.EditField
        RollerAnglesEditFieldLabel      matlab.ui.control.Label
        LengthUnitsDropDown_6           matlab.ui.control.DropDown
        RollerLengthEditField           matlab.ui.control.NumericEditField
        RollerLengthEditFieldLabel      matlab.ui.control.Label
        LengthUnitsDropDown_5           matlab.ui.control.DropDown
        RollerRadiusEditField           matlab.ui.control.NumericEditField
        RollerRadiusEditFieldLabel      matlab.ui.control.Label
        NumRollersperwheelEditField     matlab.ui.control.NumericEditField
        NumRollersperwheelEditFieldLabel  matlab.ui.control.Label
        LengthUnitsDropDown_4           matlab.ui.control.DropDown
        AxleLengthEditField             matlab.ui.control.NumericEditField
        AxleLengthEditFieldLabel        matlab.ui.control.Label
        LengthUnitsDropDown_3           matlab.ui.control.DropDown
        AxleRadiusEditField             matlab.ui.control.NumericEditField
        AxleRadiusEditFieldLabel        matlab.ui.control.Label
        LengthUnitsDropDown_2           matlab.ui.control.DropDown
        WheelThicknessEditField         matlab.ui.control.NumericEditField
        WheelThicknessEditFieldLabel    matlab.ui.control.Label
        LengthUnitsDropDown             matlab.ui.control.DropDown
        WheelRadiusEditField            matlab.ui.control.NumericEditField
        WheelRadiusEditFieldLabel       matlab.ui.control.Label
        LengthUnitsDropDown_15          matlab.ui.control.DropDown
        ChasisDimensionsEditField       matlab.ui.control.EditField
        ChasisDimensionsEditFieldLabel  matlab.ui.control.Label
        OmniDirectionalMobileBaseLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AngleUnitsDropDown, ArmBaseRadiusEditField, 
        % ...and 34 other components
        function VisualizeButtonPushed(app, event)

            if app.AutoUpdateVisualizationCheckBox.Value
                app.UpdateVisualizationButton.Visible = "off";
            else
                app.UpdateVisualizationButton.Visible = "on";
            end

            wheel_r = simscape.Value(app.WheelRadiusEditField.Value,...
                                     app.LengthUnitsDropDown.Value);
            wheel_thickness = simscape.Value(app.WheelThicknessEditField.Value,...
                                     app.LengthUnitsDropDown_2.Value);
            
            chasis_dim = simscape.Value(str2num(app.ChasisDimensionsEditField.Value),...
                                     app.LengthUnitsDropDown_15.Value);
            
            axle_r = simscape.Value(app.AxleRadiusEditField.Value,...
                                     app.LengthUnitsDropDown_3.Value);
            axle_l = simscape.Value(app.AxleLengthEditField.Value,...
                                     app.LengthUnitsDropDown_4.Value);
            
            num_of_rollers = app.NumRollersperwheelEditField.Value;
            
            roller_r = simscape.Value(app.RollerRadiusEditField.Value,...
                                     app.LengthUnitsDropDown_5.Value);
            roller_l = simscape.Value(app.RollerLengthEditField.Value,...
                                     app.LengthUnitsDropDown_6.Value);
            
            roller_ang_temp = str2num(app.RollerAnglesEditField.Value);
                                     
            for i = 1:length(roller_ang_temp)                
                roller_ang{i} = simscape.Value(roller_ang_temp(i),app.AngleUnitsDropDown.Value);                                
            end
            
            ArmBaseParams.r = simscape.Value(app.ArmBaseRadiusEditField.Value,...
                                     app.LengthUnitsDropDown_7.Value);
            ArmBaseParams.l = simscape.Value(app.ArmBaseThicknessEditField.Value,...
                                     app.LengthUnitsDropDown_8.Value);
            
            arm_link_l_temp = str2num(app.ArmLinkLengthsEditField.Value);
            arm_link_t_temp = str2num(app.ArmLinkThicknessesEditField.Value);
            arm_link_w_temp = str2num(app.ArmLinkWidthsEditField.Value);
            color = {[1.0 0.4 0.4],[1.0 0.4 0.4],[1.0 0.4 0.4]};
            
            for i = 1:length(arm_link_l_temp)                
                ArmLinkParams(i).l = simscape.Value(arm_link_l_temp(i),app.LengthUnitsDropDown_9.Value);
                ArmLinkParams(i).t = simscape.Value(arm_link_t_temp(i),app.LengthUnitsDropDown_10.Value);
                ArmLinkParams(i).w = simscape.Value(arm_link_w_temp(i),app.LengthUnitsDropDown_11.Value);
                ArmLinkParams(i).color = color{i};
            end
            
            EndEffParams.r = simscape.Value(app.EndEffectorRadiusEditField.Value,...
                                     app.LengthUnitsDropDown_12.Value);
            EndEffParams.l = simscape.Value(app.EndEffectorLengthEditField.Value,...
                                     app.LengthUnitsDropDown_13.Value);
            EndEffParams.color = [1.0 0.4 0.4];
            
            GripperParams.dim = simscape.Value(str2num(app.GripperDimensionsEditField.Value),...
                                     app.LengthUnitsDropDown_14.Value);
            
            GripperParams.color = [0.2 0.2 0.2];
            
            [mobileRobot_mb,op] = makeMobileRobot(wheel_r,...
                                             wheel_thickness,...
                                             roller_r,...
                                             roller_l,...
                                             roller_ang,...
                                             num_of_rollers,...
                                             chasis_dim,...
                                             axle_r,...
                                             axle_l,...
                                             ArmBaseParams,...
                                             ArmLinkParams,...
                                             EndEffParams,...
                                             GripperParams);

            if app.AutoUpdateVisualizationCheckBox.Value ...
                    || (isa(event,'matlab.ui.eventdata.ButtonPushedData')...
                    && strcmp(event.Source.Text,'Update Visualization'))
               
                m = mobileRobot_mb.compile;
                s = m.computeState(op);
                m.visualize(s,'viz');

            end

            if contains(class(event),'ButtonPushed') && strcmp(event.Source.Text,'Convert to Simulink Model')
                mdlname = 'mobileRobotModel';
                makeBlockDiagram(mobileRobot_mb,op,mdlname);              
            end
            
        end

        % Callback function
        function WheelRadiusEditFieldValueChanged(app, event)
            value = app.WheelRadiusEditField.Value;
            
        end

        % Callback function
        function RollerAnglesEditFieldValueChanged(app, event)
            value = app.RollerAnglesEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.OmniDirectionalMobileBaseLabel = uilabel(app.GridLayout3);
            app.OmniDirectionalMobileBaseLabel.Text = 'Omni Directional Mobile Base';
            app.ChasisDimensionsEditFieldLabel = uilabel(app.GridLayout3);
            app.ChasisDimensionsEditFieldLabel.Text = 'Chasis Dimensions';
            app.ChasisDimensionsEditField = uieditfield(app.GridLayout3, 'text');
            app.ChasisDimensionsEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.ChasisDimensionsEditField.Value = '[0.75 0.3 0.1 ]';
            app.LengthUnitsDropDown_15 = uidropdown(app.GridLayout3);
            app.LengthUnitsDropDown_15.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_15.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_15.Value = 'm';
            app.WheelRadiusEditFieldLabel = uilabel(app.GridLayout3);
            app.WheelRadiusEditFieldLabel.Text = 'Wheel Radius ';
            app.WheelRadiusEditField = uieditfield(app.GridLayout3, 'numeric');
            app.WheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.WheelRadiusEditField.Value = 0.05;
            app.LengthUnitsDropDown = uidropdown(app.GridLayout3);
            app.LengthUnitsDropDown.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown.Value = 'm';
            app.WheelThicknessEditFieldLabel = uilabel(app.GridLayout3);
            app.WheelThicknessEditFieldLabel.Text = 'Wheel Thickness ';
            app.WheelThicknessEditField = uieditfield(app.GridLayout3, 'numeric');
            app.WheelThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.WheelThicknessEditField.Value = 0.07;
            app.LengthUnitsDropDown_2 = uidropdown(app.GridLayout3);
            app.LengthUnitsDropDown_2.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_2.Value = 'm';
            app.AxleRadiusEditFieldLabel = uilabel(app.GridLayout3);
            app.AxleRadiusEditFieldLabel.Text = 'Axle Radius';
            app.AxleRadiusEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AxleRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.AxleRadiusEditField.Value = 0.02;
            app.LengthUnitsDropDown_3 = uidropdown(app.GridLayout3);
            app.LengthUnitsDropDown_3.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_3.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_3.Value = 'm';
            app.AxleLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.AxleLengthEditFieldLabel.Text = 'Axle Length';
            app.AxleLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AxleLengthEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.AxleLengthEditField.Value = 0.15;
            app.LengthUnitsDropDown_4 = uidropdown(app.GridLayout3);
            app.LengthUnitsDropDown_4.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_4.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_4.Value = 'm';
            app.NumRollersperwheelEditFieldLabel = uilabel(app.GridLayout3);
            app.NumRollersperwheelEditFieldLabel.Text = 'Num Rollers per wheel';
            app.NumRollersperwheelEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NumRollersperwheelEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.NumRollersperwheelEditField.Value = 12;
            app.RollerRadiusEditFieldLabel = uilabel(app.GridLayout3);
            app.RollerRadiusEditFieldLabel.Text = 'Roller Radius';
            app.RollerRadiusEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RollerRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.RollerRadiusEditField.Value = 0.01;
            app.LengthUnitsDropDown_5 = uidropdown(app.GridLayout3);
            app.LengthUnitsDropDown_5.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_5.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_5.Value = 'm';
            app.RollerLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.RollerLengthEditFieldLabel.Text = 'Roller Length';
            app.RollerLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RollerLengthEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.RollerLengthEditField.Value = 0.08;
            app.LengthUnitsDropDown_6 = uidropdown(app.GridLayout3);
            app.LengthUnitsDropDown_6.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_6.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_6.Value = 'm';
            app.RollerAnglesEditFieldLabel = uilabel(app.GridLayout3);
            app.RollerAnglesEditFieldLabel.Text = 'Roller Angles';
            app.RollerAnglesEditField = uieditfield(app.GridLayout3, 'text');
            app.RollerAnglesEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.RollerAnglesEditField.Value = '[-pi/4 pi/4 pi/4 -pi/4]';
            app.AngleUnitsDropDown = uidropdown(app.GridLayout3);
            app.AngleUnitsDropDown.Items = {'rad', 'deg'};
            app.AngleUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.AngleUnitsDropDown.Value = 'rad';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = 'mobileRobotIcon.JPG';
            app.RightPanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.ArmLabel = uilabel(app.GridLayout2);
            app.ArmLabel.Text = 'Arm';
            app.ArmBaseRadiusEditFieldLabel = uilabel(app.GridLayout2);
            app.ArmBaseRadiusEditFieldLabel.Text = 'Arm Base Radius';
            app.ArmBaseRadiusEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ArmBaseRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.ArmBaseRadiusEditField.Value = 0.075;
            app.LengthUnitsDropDown_7 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_7.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_7.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_7.Value = 'm';
            app.ArmBaseThicknessEditFieldLabel = uilabel(app.GridLayout2);
            app.ArmBaseThicknessEditFieldLabel.Text = 'Arm Base Thickness';
            app.ArmBaseThicknessEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ArmBaseThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.ArmBaseThicknessEditField.Value = 0.05;
            app.LengthUnitsDropDown_8 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_8.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_8.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_8.Value = 'm';
            app.ArmLinkLengthsEditFieldLabel = uilabel(app.GridLayout2);
            app.ArmLinkLengthsEditFieldLabel.Text = 'Arm Link Lengths';
            app.ArmLinkLengthsEditField = uieditfield(app.GridLayout2, 'text');
            app.ArmLinkLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.ArmLinkLengthsEditField.Value = '[0.2 0.2 0.1 ]';
            app.LengthUnitsDropDown_9 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_9.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_9.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_9.Value = 'm';
            app.ArmLinkWidthsEditFieldLabel = uilabel(app.GridLayout2);
            app.ArmLinkWidthsEditFieldLabel.Text = 'Arm Link Widths';
            app.ArmLinkWidthsEditField = uieditfield(app.GridLayout2, 'text');
            app.ArmLinkWidthsEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.ArmLinkWidthsEditField.Value = '[0.05 0.05 0.05]';
            app.LengthUnitsDropDown_10 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_10.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_10.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_10.Value = 'm';
            app.ArmLinkThicknessesEditFieldLabel = uilabel(app.GridLayout2);
            app.ArmLinkThicknessesEditFieldLabel.Text = 'Arm Link Thicknesses';
            app.ArmLinkThicknessesEditField = uieditfield(app.GridLayout2, 'text');
            app.ArmLinkThicknessesEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.ArmLinkThicknessesEditField.Value = '[0.03 0.03 0.03]';
            app.LengthUnitsDropDown_11 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_11.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_11.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_11.Value = 'm';
            app.EndEffectorRadiusEditFieldLabel = uilabel(app.GridLayout2);
            app.EndEffectorRadiusEditFieldLabel.Text = 'End Effector Radius';
            app.EndEffectorRadiusEditField = uieditfield(app.GridLayout2, 'numeric');
            app.EndEffectorRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.EndEffectorRadiusEditField.Value = 0.025;
            app.LengthUnitsDropDown_12 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_12.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_12.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_12.Value = 'm';
            app.EndEffectorLengthEditFieldLabel = uilabel(app.GridLayout2);
            app.EndEffectorLengthEditFieldLabel.Text = 'End Effector Length';
            app.EndEffectorLengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.EndEffectorLengthEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.EndEffectorLengthEditField.Value = 0.025;
            app.LengthUnitsDropDown_13 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_13.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_13.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_13.Value = 'm';
            app.GripperDimensionsEditFieldLabel = uilabel(app.GridLayout2);
            app.GripperDimensionsEditFieldLabel.Text = 'Gripper Dimensions';
            app.GripperDimensionsEditField = uieditfield(app.GridLayout2, 'text');
            app.GripperDimensionsEditField.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.GripperDimensionsEditField.Value = '[0.08 0.02 0.005]';
            app.LengthUnitsDropDown_14 = uidropdown(app.GridLayout2);
            app.LengthUnitsDropDown_14.Items = {'m', 'cm', 'in', 'ft'};
            app.LengthUnitsDropDown_14.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.LengthUnitsDropDown_14.Value = 'm';
            app.Panel = uipanel(app.GridLayout2);
            app.AutoUpdateVisualizationCheckBox = uicheckbox(app.Panel);
            app.AutoUpdateVisualizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.AutoUpdateVisualizationCheckBox.Tooltip = {'Visualization automatically updates when the value of any parameter is changed.'};
            app.AutoUpdateVisualizationCheckBox.Text = 'Auto Update Visualization';
            app.ConverttoSimulinkModelButton = uibutton(app.Panel, 'push');
            app.ConverttoSimulinkModelButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.ConverttoSimulinkModelButton.Text = 'Convert to Simulink Model';
            app.UpdateVisualizationButton = uibutton(app.Panel, 'push');
            app.UpdateVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.UpdateVisualizationButton.Text = 'Update Visualization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = buildMobileRobotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end